classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Image_2                    matlab.ui.control.Image
        VegeationkmLabel           matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        ReflectivityDropDown       matlab.ui.control.DropDown
        ReflectivityDropDownLabel  matlab.ui.control.Label
        Reflectivity               matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        WaterkmLabel               matlab.ui.control.Label
        VegetationLabel            matlab.ui.control.Label
        WaterLabel                 matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        waterkmEditField           matlab.ui.control.NumericEditField
        waterEditField             matlab.ui.control.NumericEditField
        LocationDropDown           matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        LocationsLabel             matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        TwoYearSlider              matlab.ui.control.RangeSlider
        YearsLabel                 matlab.ui.control.Label
        PlayButton                 matlab.ui.control.Button
        YearSlider                 matlab.ui.control.Slider
        YearSliderLabel            matlab.ui.control.Label
        LocationnameLabel          matlab.ui.control.Label
        BaseImageAxes              matlab.ui.control.UIAxes
        OverlayImageAxes           matlab.ui.control.UIAxes
        DifferenceImageAxes        matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        SensitivitySlider          matlab.ui.control.Slider
        SensitivitySliderLabel     matlab.ui.control.Label
        RoadsDropDown              matlab.ui.control.DropDown
        RoadsDropDownLabel         matlab.ui.control.Label
        VegetationDropDown         matlab.ui.control.DropDown
        VegetationDropDownLabel    matlab.ui.control.Label
        WaterDropDown              matlab.ui.control.DropDown
        WaterDropDownLabel         matlab.ui.control.Label
        Enhance                    matlab.ui.control.Button
        Roads                      matlab.ui.control.Button
        Home                       matlab.ui.control.Button
        Difference                 matlab.ui.control.Button
        Water                      matlab.ui.control.Button
        Vegetation                 matlab.ui.control.Button
        SatelliteButton            matlab.ui.control.Button
        AnalysisLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        LocationButtons matlab.ui.control.Button = matlab.ui.control.Button.empty;
        ImageCollections containers.Map = containers.Map('KeyType','char','ValueType','any');
        OverlayType = "";
        WaterMethod = "ENSEMBLE";
        VegetationMethod = "ENSEMBLE";
        RoadsMethod = "ENSEMBLE";
        ReflectivityMethod = "ENSEMBLE";
        ShowDifference = false;
        PlayTimer timer;

    end
    
    methods (Access = private)
        function updateImage(app, value)
            currentLocation = app.LocationnameLabel.Text;

            nearestIndex = getNearestIndex(app, currentLocation, value);
            imageInfoTable = app.ImageCollections(currentLocation);

            frame = imread(imageInfoTable.filePath{nearestIndex});
            image = imshow(frame, 'Parent', app.BaseImageAxes);
            image.HitTest       = 'off';
            image.PickableParts = 'none';

            
        end

        function snappedDecimalDate = snapDecimalDate(app, currentLocation, value)
            imageInfoTable = app.ImageCollections(currentLocation);
            decimalDates   = imageInfoTable.decimalDate;

            nearestIndex = getNearestIndex(app, currentLocation, value);
            snappedDecimalDate     = decimalDates(nearestIndex);            
        end

        function nearestIndex = getNearestIndex(app, currentLocation, value)
            imageInfoTable = app.ImageCollections(currentLocation);
            decimalDates   = imageInfoTable.decimalDate;

            [~, nearestIndex] = min(abs(decimalDates - value));
        end

        function updateImageOverlays(app, overlayType)
            app.OverlayType = overlayType;
            currentLocation = app.LocationnameLabel.Text;

            imageInfoTable = app.ImageCollections(currentLocation);
            decimalDates   = imageInfoTable.decimalDate;

            [~, nearestIndex]      = min(abs(decimalDates - app.YearSlider.Value));
            snappedDecimalDate     = decimalDates(nearestIndex);
            
            % Reset overlays
            app.OverlayImageAxes.Visible = true;
            app.OverlayImageAxes.HitTest = true;
            app.DifferenceImageAxes.Visible = true;
            app.DifferenceImageAxes.HitTest = true;
            cla(app.OverlayImageAxes);
            cla(app.DifferenceImageAxes);

            if overlayType == "vegetation"
                vegetationAnalysisOutput = VegetationSegment(imageInfoTable.filePath{nearestIndex}, 'Method', app.VegetationMethod, 'Sensitivity', app.SensitivitySlider.Value);
                frame = vegetationAnalysisOutput.overlayImage;
                overlay = imshow(frame, 'Parent', app.OverlayImageAxes);
                overlay.AlphaData = vegetationAnalysisOutput.overlayAlpha;
                overlay.HitTest = 'off';
                overlay.PickableParts = 'none';
                meanVARI = vegetationAnalysisOutput.stats.vegetationPercentage;
                app.EditField.Value = meanVARI ;
                vegarea = vegetationAnalysisOutput.stats.realWorldArea;
                app.EditField_2.Value = vegarea ;
                
            end
            if overlayType == "water"
                waterAnalysisOutput = WaterBodySegment(imageInfoTable.filePath{nearestIndex}, 'Method', app.WaterMethod, 'Sensitivity', app.SensitivitySlider.Value);
                frame = waterAnalysisOutput.overlayImage;
                overlay = imshow(frame, 'Parent', app.OverlayImageAxes);
                overlay.AlphaData = waterAnalysisOutput.overlayAlpha;
                overlay.HitTest       = 'off';
                overlay.PickableParts = 'none';
                waterPct = waterAnalysisOutput.stats.waterPercentage;
                app.waterEditField.Value = waterPct;
                
                real_world = waterAnalysisOutput.stats.realWorldArea;
                app.waterkmEditField.Value = real_world;
                 
            end
            if overlayType == "roads"
                roadAnalysisOutput = roadNetworkExtraction(imageInfoTable.filePath{nearestIndex}, 'Method', app.RoadsMethod, 'Sensitivity', app.SensitivitySlider.Value);
                frame = roadAnalysisOutput.overlay;
                overlay = imshow(frame, 'Parent', app.OverlayImageAxes);
                overlay.AlphaData = roadAnalysisOutput.overlayAlpha;
                overlay.HitTest       = 'off';
                overlay.PickableParts = 'none'; 
                roadnum = roadAnalysisOutput.stats.waterPercentage;
                app.waterEditField.Value = waterPct;

            end
            if overlayType == "reflectivity"
                reflictivityAnalysisOutput = visualizeReflectiveSurfaces(imageInfoTable.filePath{nearestIndex}, 'Method', app.ReflectivityMethod, 'Sensitivity', app.SensitivitySlider.Value);
                frame = reflictivityAnalysisOutput.overlayImage;
                overlay = imshow(frame, 'Parent', app.OverlayImageAxes);
                overlay.AlphaData = reflictivityAnalysisOutput.overlayAlpha;
                overlay.HitTest       = 'off';
                overlay.PickableParts = 'none';                 
            end
            if overlayType == "none"
                app.OverlayImageAxes.Visible = false;
                app.OverlayImageAxes.HitTest = false;
            end

            if app.ShowDifference
                selectedYears = app.TwoYearSlider.Value;
                startYear = selectedYears(1);
                endYear   = selectedYears(2);
        
                startYearIndex = getNearestIndex(app, currentLocation, startYear);
                endYearIndex   = getNearestIndex(app, currentLocation, endYear);
        
                startImagePath = imageInfoTable.filePath{startYearIndex};
                endImagePath   = imageInfoTable.filePath{endYearIndex};
            
                if overlayType == "vegetation"
                    startOverlayResult = vegetation(startImagePath);
                    endOverlayResult   = vegetation(endImagePath);
    
                    firstImageToCompare  = startOverlayResult.overlay;
                    secondImageToCompare = endOverlayResult.overlay;
                end
            
                if overlayType == "water"
                    startOverlayResult = WaterBodySegment(startImagePath);
                    endOverlayResult   = WaterBodySegment(endImagePath);

                    firstImageToCompare  = startOverlayResult.overlayImage;
                    secondImageToCompare = endOverlayResult.overlayImage;
                end

                if overlayType == "roads"
                    startOverlayResult = roadNetworkExtraction(startImagePath);
                    endOverlayResult   = roadNetworkExtraction(endImagePath);

                    firstImageToCompare  = startOverlayResult.overlayImage;
                    secondImageToCompare = endOverlayResult.overlayImage;
                end

                if overlayType == "reflictivity"
                    startOverlayResult = visualizeReflectiveSurfaces(startImagePath);
                    endOverlayResult   = visualizeReflectiveSurfaces(endImagePath);

                    firstImageToCompare  = startOverlayResult.overlayImage;
                    secondImageToCompare = endOverlayResult.overlayImage;
                end
            
                if overlayType == "none"
                    firstImageToCompare  = imread(startImagePath);
                    secondImageToCompare = imread(endImagePath);
                    app.DifferenceImageAxes.Visible = false;
                    app.DifferenceImageAxes.HitTest = false;
                end

                differenceResult = DifferenceHighlight(firstImageToCompare, secondImageToCompare);
                frame = differenceResult.overlayImage;
                overlay = imshow(frame, 'Parent', app.DifferenceImageAxes);
                overlay.AlphaData = differenceResult.overlayAlpha;
                overlay.HitTest       = 'off';
                overlay.PickableParts = 'none';
            else
                cla(app.DifferenceImageAxes);
            end
        end

        function advanceSlider(app)
            currentLocation = app.LocationnameLabel.Text;

            imageInfoTable = app.ImageCollections(currentLocation);
            dates = imageInfoTable.decimalDate;

            currentYear = app.YearSlider.Value;
            [~, nearestIndex] = min(abs(dates - app.YearSlider.Value));


            nearestIndex = mod(nearestIndex, height(imageInfoTable)) + 1;
            % nearestIndex = nearestIndex + 1


            newValue = dates(nearestIndex);
            app.YearSlider.Value = newValue;
            app.YearSliderValueChanged(struct("Value", newValue));
        
        end

        function generateLocationButtons(app)
            datasetPath = fullfile(pwd, "Datasets");
            if ~isfolder(datasetPath)
                warning('Folder "%s" not found. No location buttons created.', datasetPath);
                return;
            end
            subDirectories = dir(datasetPath);
            subDirectories = subDirectories([subDirectories.isdir]);
            subDirectories = subDirectories(~ismember({subDirectories.name}, {'.', '..'}));

            locationNames                 = {subDirectories.name};
            app.LocationDropDown.Items   = locationNames;
            app.LocationDropDown.Value   = locationNames{1};
        end

        function CopyUIAxesZoom(app, source, destination)        
            destination.XLim = source.XLim;
            destination.YLim = source.YLim;
        
            destination.DataAspectRatio         = source.DataAspectRatio;
            destination.DataAspectRatioMode     = source.DataAspectRatioMode;
            destination.PlotBoxAspectRatio      = source.PlotBoxAspectRatio;
            destination.PlotBoxAspectRatioMode  = source.PlotBoxAspectRatioMode;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            generateLocationButtons(app);
            if app.ShowDifference
                app.TwoYearSlider.Visible = 'on';
                app.YearSlider.Visible = 'off';
            else
                app.TwoYearSlider.Visible = 'off';
                app.YearSlider.Visible = 'on';
            end

            app.PlayTimer = timer("ExecutionMode","fixedRate", ...
                             "Period",0.2, ...
                             "BusyMode","drop", ...
                             "TimerFcn",@(~,~)app.advanceSlider);

            app.LocationDropDownValueChanged(struct());
            app.WaterDropDownValueChanged(struct());
            app.OverlayImageAxes.Visible = false;
            app.OverlayImageAxes.HitTest = false;
            app.DifferenceImageAxes.Visible = false;
            app.DifferenceImageAxes.HitTest = false;
            

        end

        % Value changing function: YearSlider
        function YearSliderValueChanging(app, event)

            YearSliderValueChanged(app, event);

        end

        % Button pushed function: Vegetation
        function VegetationPushed(app, event)

            app.updateImageOverlays("vegetation");
            
        end

        % Value changed function: YearSlider
        function YearSliderValueChanged(app, event)

            app.updateImage(event.Value);

            currentLocation = app.LocationnameLabel.Text;
            snappedDecimalDate = snapDecimalDate(app, currentLocation, event.Value);
            app.YearSlider.Value = snappedDecimalDate;
            
            app.updateImageOverlays(app.OverlayType);

        end

        % Button pushed function: SatelliteButton
        function SatelliteButtonPushed(app, event)

            app.updateImageOverlays("none");

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            if strcmp(app.PlayTimer.Running,"off")
                start(app.PlayTimer);
            else
                stop(app.PlayTimer);
            end

        end

        % Button pushed function: Water
        function WaterButtonPushed(app, event)
            
            app.updateImageOverlays("water");
            
            
        end

        % Value changed function: TwoYearSlider
        function TwoYearSliderValueChanged(app, event)
            
            value = app.TwoYearSlider.Value;
            year1 = value(1);
            year2 = value(2);

            currentLocation = app.LocationnameLabel.Text;
            snappedDecimalDate1 = snapDecimalDate(app, currentLocation, event.Value(1));
            snappedDecimalDate2 = snapDecimalDate(app, currentLocation, event.Value(2));
    
            app.TwoYearSlider.Value = [snappedDecimalDate1, snappedDecimalDate2];
            app.YearSlider.Value = snappedDecimalDate1;
            
            app.updateImage(year1);
            app.updateImageOverlays(app.OverlayType);
            
        end

        % Button pushed function: Difference
        function DifferenceButtonPushed(app, event)
        
            app.ShowDifference = ~app.ShowDifference;
            if app.ShowDifference
                app.TwoYearSlider.Visible = 'on';
                app.YearSlider.Visible = 'off';
            else
                app.TwoYearSlider.Visible = 'off';
                app.YearSlider.Visible = 'on';
            end

        end

        % Value changing function: TwoYearSlider
        function TwoYearSliderValueChanging(app, event)
            
            TwoYearSliderValueChanged(app,event);
            
        end

        % Button pushed function: Home
        function HomeButtonPushed(app, event)
            
        end

        % Button pushed function: Roads
        function RoadsButtonPushed(app, event)
             app.updateImageOverlays("roads");            
        end

        % Button pushed function: Enhance
        function EnhanceButtonPushed(app, event)
            
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)


            location = app.LocationDropDown.Value;
            app.LocationnameLabel.Text = location;
            location = app.LocationDropDown.Value;
            Computervisiontest(location)
            scriptDir     = fileparts(mfilename('fullpath'));
            baseDir       = fullfile(scriptDir, 'Datasets');
            locationFolder = fullfile(baseDir, location, 'common_crop');
            
      
            imageFiles = dir(fullfile(locationFolder, "*.jpg"));

            filenamePattern = "^([A-Za-z0-9]*)_?((?:19|20)\d{2})_([0][1-9]|1[0-2])\.jpg$";

            imageStructs = struct('month',       {}, ...
                                  'year',        {}, ...
                                  'decimalDate', {}, ...
                                  'filePath',    {}  );


            for k = 1:numel(imageFiles)
                fname = imageFiles(k).name;
                tokens = regexp(fname, filenamePattern, 'tokens', 'once');
                if ~isempty(tokens)
                    yearNumber  = str2double(tokens{2});
                    monthNumber = str2double(tokens{3});
                    decimalDate  = yearNumber + (monthNumber-1)/12;
                    imageStructs(end+1) = struct('month',       monthNumber, ...
                                                 'year',        yearNumber, ...
                                                 'decimalDate', decimalDate, ...
                                                 'filePath',    fullfile(imageFiles(k).folder, imageFiles(k).name) );
                end
            end

            imageInfoTable = struct2table(imageStructs);
            if ~isempty(imageInfoTable)
                imageInfoTable = sortrows(imageInfoTable, 'decimalDate');
            end
            if isempty(imageInfoTable)
                warning('No images found for location "%s".', location);
                return;
            end
    

            app.ImageCollections(location) = imageInfoTable;

            % Reconfigure slider
            decimalDates = imageInfoTable.decimalDate;

            app.YearSlider.Limits      = [floor(decimalDates(1)) ceil(decimalDates(end))];
            app.YearSlider.MajorTicks  = decimalDates;
            app.YearSlider.MinorTicks  = [];
            app.YearSlider.Step = 0.01;

            app.TwoYearSlider.Limits      = [floor(decimalDates(1)) ceil(decimalDates(end))];
            app.TwoYearSlider.MajorTicks  = decimalDates;
            app.TwoYearSlider.MinorTicks  = [];
            app.TwoYearSlider.Step = 0.01;
    
            app.YearSlider.Value       = decimalDates(1);

            firstIndex  = 1;
            app.YearSlider.Value = imageInfoTable.decimalDate(firstIndex);
            frame = imread(imageInfoTable.filePath{firstIndex});
            image = imshow(frame, 'Parent', app.BaseImageAxes);
            image.HitTest       = 'off';
            image.PickableParts = 'none';
            
        end

        % Value changed function: WaterDropDown
        function WaterDropDownValueChanged(app, event)
            
            value = app.WaterDropDown.Value;

            app.WaterMethod = value;
            
        end

        % Button down function: BaseImageAxes
        function BaseImageAxesButtonDown(app, event)
            
            "hello"
            app.CopyUIAxesZoom(app.BaseImageAxes, app.OverlayImageAxes)
            app.CopyUIAxesZoom(app.BaseImageAxes, app.DifferenceImageAxes);

        end

        % Value changed function: VegetationDropDown
        function VegetationDropDownValueChanged(app, event)
            value = app.VegetationDropDown.Value;
            app.VegetationMethod = value;
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            value = app.SensitivitySlider.Value;
            
        end

        % Value changed function: RoadsDropDown
        function RoadsDropDownValueChanged(app, event)
            value = app.RoadsDropDown.Value;

            app.RoadsMethod = value;
            
        end

        % Value changed function: ReflectivityDropDown
        function ReflectivityDropDownValueChanged(app, event)
            value = app.ReflectivityDropDown.Value;

            app.ReflectivityMethod = value;
        end

        % Button pushed function: Reflectivity
        function ReflectivityButtonPushed(app, event)
             app.updateImageOverlays("reflectivity");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {494, 494, 494};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {494, 494};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 264};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LocationsLabel = uilabel(app.LeftPanel);
            app.LocationsLabel.Text = 'Locations';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.LocationDropDown = uidropdown(app.LeftPanel);
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.waterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.waterkmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.waterkmEditField.AllowEmpty = 'on';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.WaterLabel = uilabel(app.LeftPanel);
            app.WaterLabel.Text = 'Water (%)';
            app.VegetationLabel = uilabel(app.LeftPanel);
            app.VegetationLabel.Text = 'Vegetation (%)';
            app.WaterkmLabel = uilabel(app.LeftPanel);
            app.WaterkmLabel.Text = 'Water (km²)';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sauron.png');
            app.Reflectivity = uibutton(app.LeftPanel, 'push');
            app.Reflectivity.ButtonPushedFcn = createCallbackFcn(app, @ReflectivityButtonPushed, true);
            app.Reflectivity.Icon = fullfile(pathToMLAPP, 'AdobeStock_550718452.jpeg');
            app.Reflectivity.Text = '';
            app.ReflectivityDropDownLabel = uilabel(app.LeftPanel);
            app.ReflectivityDropDownLabel.Text = 'Reflectivity';
            app.ReflectivityDropDown = uidropdown(app.LeftPanel);
            app.ReflectivityDropDown.Items = {'ENSEMBLE', 'Luminosity', 'TopPercent'};
            app.ReflectivityDropDown.ValueChangedFcn = createCallbackFcn(app, @ReflectivityDropDownValueChanged, true);
            app.ReflectivityDropDown.Value = 'ENSEMBLE';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.VegeationkmLabel = uilabel(app.LeftPanel);
            app.VegeationkmLabel.Text = 'Vegeation (km²)';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'sauron.png');
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.DifferenceImageAxes = uiaxes(app.GridLayout2);
            app.DifferenceImageAxes.XTick = [];
            app.DifferenceImageAxes.YTick = [];
            app.OverlayImageAxes = uiaxes(app.GridLayout2);
            app.OverlayImageAxes.XTick = [];
            app.OverlayImageAxes.YTick = [];
            app.BaseImageAxes = uiaxes(app.GridLayout2);
            app.BaseImageAxes.XTick = [];
            app.BaseImageAxes.YTick = [];
            app.BaseImageAxes.ButtonDownFcn = createCallbackFcn(app, @BaseImageAxesButtonDown, true);
            app.LocationnameLabel = uilabel(app.GridLayout2);
            app.LocationnameLabel.Text = 'Location name';
            app.YearSliderLabel = uilabel(app.GridLayout2);
            app.YearSliderLabel.Text = 'Year';
            app.YearSlider = uislider(app.GridLayout2);
            app.YearSlider.Limits = [1990 2025];
            app.YearSlider.ValueChangedFcn = createCallbackFcn(app, @YearSliderValueChanged, true);
            app.YearSlider.ValueChangingFcn = createCallbackFcn(app, @YearSliderValueChanging, true);
            app.YearSlider.MinorTicks = [1990 1991 1992 1993 1994 1995 1996 1997 1998 1999 2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022 2023 2024 2025];
            app.YearSlider.Step = 0.01;
            app.YearSlider.Value = 1990;
            app.PlayButton = uibutton(app.GridLayout2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.YearsLabel = uilabel(app.GridLayout2);
            app.YearsLabel.Text = '';
            app.TwoYearSlider = uislider(app.GridLayout2, 'range');
            app.TwoYearSlider.ValueChangedFcn = createCallbackFcn(app, @TwoYearSliderValueChanged, true);
            app.TwoYearSlider.ValueChangingFcn = createCallbackFcn(app, @TwoYearSliderValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalysisLabel = uilabel(app.RightPanel);
            app.AnalysisLabel.Text = 'Analysis';
            app.SatelliteButton = uibutton(app.RightPanel, 'push');
            app.SatelliteButton.ButtonPushedFcn = createCallbackFcn(app, @SatelliteButtonPushed, true);
            app.SatelliteButton.Text = 'Satellite';
            app.Vegetation = uibutton(app.RightPanel, 'push');
            app.Vegetation.ButtonPushedFcn = createCallbackFcn(app, @VegetationPushed, true);
            app.Vegetation.Icon = fullfile(pathToMLAPP, 'AdobeStock_550718452.jpeg');
            app.Vegetation.Text = '';
            app.Water = uibutton(app.RightPanel, 'push');
            app.Water.ButtonPushedFcn = createCallbackFcn(app, @WaterButtonPushed, true);
            app.Water.Icon = fullfile(pathToMLAPP, 'AdobeStock_550718452.jpeg');
            app.Water.Text = '';
            app.Difference = uibutton(app.RightPanel, 'push');
            app.Difference.ButtonPushedFcn = createCallbackFcn(app, @DifferenceButtonPushed, true);
            app.Difference.Text = 'Difference';
            app.Home = uibutton(app.RightPanel, 'push');
            app.Home.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.Home.Text = 'Home';
            app.Roads = uibutton(app.RightPanel, 'push');
            app.Roads.ButtonPushedFcn = createCallbackFcn(app, @RoadsButtonPushed, true);
            app.Roads.Icon = fullfile(pathToMLAPP, 'AdobeStock_550718452.jpeg');
            app.Roads.Text = '';
            app.Enhance = uibutton(app.RightPanel, 'push');
            app.Enhance.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButtonPushed, true);
            app.Enhance.Text = 'Enhance';
            app.WaterDropDownLabel = uilabel(app.RightPanel);
            app.WaterDropDownLabel.Text = 'Water';
            app.WaterDropDown = uidropdown(app.RightPanel);
            app.WaterDropDown.Items = {'ENSEMBLE', 'MNDWI', 'Blue-Green', 'AWEI', 'OTSU', 'HSV'};
            app.WaterDropDown.ValueChangedFcn = createCallbackFcn(app, @WaterDropDownValueChanged, true);
            app.WaterDropDown.Value = 'ENSEMBLE';
            app.VegetationDropDownLabel = uilabel(app.RightPanel);
            app.VegetationDropDownLabel.Text = 'Vegetation';
            app.VegetationDropDown = uidropdown(app.RightPanel);
            app.VegetationDropDown.Items = {'NDVI', 'EVI', 'SAVI', 'MSAVI', 'GNDVI', 'VARI', 'ExG', 'GLI', 'NGRDI', 'TGI', 'ENSEMBLE'};
            app.VegetationDropDown.ValueChangedFcn = createCallbackFcn(app, @VegetationDropDownValueChanged, true);
            app.VegetationDropDown.Value = 'ENSEMBLE';
            app.RoadsDropDownLabel = uilabel(app.RightPanel);
            app.RoadsDropDownLabel.Text = 'Roads';
            app.RoadsDropDown = uidropdown(app.RightPanel);
            app.RoadsDropDown.Items = {'ENSEMBLE', 'KMeansCanny', 'Geometry', 'ActiveContour'};
            app.RoadsDropDown.ValueChangedFcn = createCallbackFcn(app, @RoadsDropDownValueChanged, true);
            app.RoadsDropDown.Value = 'ENSEMBLE';
            app.SensitivitySliderLabel = uilabel(app.RightPanel);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.RightPanel);
            app.SensitivitySlider.Limits = [0 1];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end